classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel_Result               matlab.ui.container.Panel
        GridLayout9_4              matlab.ui.container.GridLayout
        Button_Calculate           matlab.ui.control.Button
        SubPanel_InfluenceLine     matlab.ui.container.Panel
        GridLayout14_4             matlab.ui.container.GridLayout
        Table_InfluenceLine        matlab.ui.control.Table
        RemoveRowButton_11         matlab.ui.control.Button
        AddRowButton_11            matlab.ui.control.Button
        DropDown_GewnschterOutput  matlab.ui.control.DropDown
        GewnschterOutputLabel      matlab.ui.control.Label
        Button_ClosePanel2_4       matlab.ui.control.Button
        Panel_Section              matlab.ui.container.Panel
        GridLayout9_3              matlab.ui.container.GridLayout
        SubPanel_Section           matlab.ui.container.Panel
        GridLayout14_3             matlab.ui.container.GridLayout
        Table_Section              matlab.ui.control.Table
        RemoveRowButton_10         matlab.ui.control.Button
        AddRowButton_10            matlab.ui.control.Button
        Button_ClosePanel2_3       matlab.ui.control.Button
        Panel_Material             matlab.ui.container.Panel
        GridLayout9_2              matlab.ui.container.GridLayout
        SubPanel_Material          matlab.ui.container.Panel
        GridLayout14_2             matlab.ui.container.GridLayout
        RemoveRowButton_9          matlab.ui.control.Button
        AddRowButton_9             matlab.ui.control.Button
        Table_Material             matlab.ui.control.Table
        Button_ClosePanel2_2       matlab.ui.control.Button
        Panel_Beam                 matlab.ui.container.Panel
        GridLayout8_2              matlab.ui.container.GridLayout
        Button_ClosePanel          matlab.ui.control.Button
        SubPanel_Node              matlab.ui.container.Panel
        GridLayout10               matlab.ui.container.GridLayout
        Button_RemoveRow10         matlab.ui.control.Button
        Button_AddRow              matlab.ui.control.Button
        Table_Node                 matlab.ui.control.Table
        SubPanel_Beam              matlab.ui.container.Panel
        GridLayout11               matlab.ui.container.GridLayout
        RemoveRowButton_2          matlab.ui.control.Button
        AddRowButton_2             matlab.ui.control.Button
        Table_Beam                 matlab.ui.control.Table
        SubPanel_Support           matlab.ui.container.Panel
        GridLayout12               matlab.ui.container.GridLayout
        RemoveRowButton_3          matlab.ui.control.Button
        AddRowButton_3             matlab.ui.control.Button
        Table_Support              matlab.ui.control.Table
        SubPanel_Spring            matlab.ui.container.Panel
        GridLayout13               matlab.ui.container.GridLayout
        RemoveRowButton_4          matlab.ui.control.Button
        AddRowButton_4             matlab.ui.control.Button
        Table_Spring               matlab.ui.control.Table
        Panel_Actions              matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        Button_ClosePanel2         matlab.ui.control.Button
        SubPanel_NodalLoad         matlab.ui.container.Panel
        GridLayout14               matlab.ui.container.GridLayout
        RemoveRowButton_5          matlab.ui.control.Button
        AddRowButton_5             matlab.ui.control.Button
        Table_NodalLoad            matlab.ui.control.Table
        SubPanel_PointLoad         matlab.ui.container.Panel
        GridLayout15               matlab.ui.container.GridLayout
        RemoveRowButton_6          matlab.ui.control.Button
        AddRowButton_6             matlab.ui.control.Button
        Table_PointLoad            matlab.ui.control.Table
        SubPanel_DistrLoad         matlab.ui.container.Panel
        GridLayout16               matlab.ui.container.GridLayout
        RemoveRowButton_7          matlab.ui.control.Button
        AddRowButton_7             matlab.ui.control.Button
        Table_DistrLoad            matlab.ui.control.Table
        SubPanel_ForcedDispl       matlab.ui.container.Panel
        GridLayout17               matlab.ui.container.GridLayout
        RemoveRowButton_8          matlab.ui.control.Button
        AddRowButton_8             matlab.ui.control.Button
        Table_ForcedDispl          matlab.ui.control.Table
        GridLayout                 matlab.ui.container.GridLayout
        Button_Actions             matlab.ui.control.Button
        Button_TestInput           matlab.ui.control.Button
        Button_Material            matlab.ui.control.Button
        Button_Result              matlab.ui.control.Button
        Button_Section             matlab.ui.control.Button
        Button_Beam                matlab.ui.control.Button
        SystemViewer               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Panels struct % maps key -> panel
        Tables struct % maps key -> table
        rowNumber % rownNumber of the highlighted cell, used to remove rows
        SupportTypes % List of support types to choose from
        ColumnFormatTemplates % formatting templates used for dropdowns in cells
    end
    
    methods (Access = private)
        
        function in = guiToInput(app)
            
        % Material
        in.Material = app.uiDataToTable(app.Table_Material);

        % Cross-sections
        in.Querschnitte = app.uiDataToTable(app.Table_Section);

        % Beam geometry
        in.Knoten = app.uiDataToTable(app.Table_Node);
        in.Staebe = app.uiDataToTable(app.Table_Beam);

        % Springs
        in.Feder = app.uiDataToTable(app.Table_Spring);

        % Supports
        in.Lager = app.buildLager(); 

        % Prescribed displacements
        in.VorgeschriebeneVerschiebung = app.uiDataToTable(app.Table_ForcedDispl);

        % Loads
        in.KnotenLasten            = app.uiDataToTable(app.Table_NodalLoad);
        in.StabLasten_konzentriert = app.uiDataToTable(app.Table_PointLoad);
        in.StabLasten_verteilt     = app.uiDataToTable(app.Table_DistrLoad);

        % Optional: Teilsysteme / Kondensation
        in.Kondensation = [];  % you can add later

        % OUTPUT SELECTION / INFLUENCE LINE 
        val = string(app.DropDown_GewnschterOutput.Value);
        switch val
            case "Schnittkräfte",       in.gew_output = 1;
            case "Einflusslinie",       in.gew_output = 2;
            case "Auflagerreaktionen",  in.gew_output = 3;
            otherwise                   in.gew_output = 1;  % default
        end

        in.Einflusslinie = app.uiDataToTable(app.Table_InfluenceLine);

        end

        function Lager = buildLager(app)

            % Column 1 cell array --> numeric array
            Knoten = cell2mat(app.Table_Support.Data(:,1));

            % Column 2 cell array --> string array
            chosen = string(app.Table_Support.Data(:,2));

            % Map string --> number based on order in app.SupportTypes
            [~, Lagerung] = ismember(chosen, app.SupportTypes);

            % Build output table
            Lager = table(Knoten, Lagerung);
        end
     
        % Work in Progress (want to simplify)
        function T = uiDataToTable(~, uitab)
            data = uitab.Data;

            % 1) Already a real table -> just return it
            if istable(data)
                T = data;
                return;
            end

            % 2) Determine how many columns the data actually has
            if isempty(data)
                % No data at all -> return empty table with no vars
                T = table();
                return;
            end
            nCols = size(data, 2);

            % 3) Get column names from the uitable
            varNames = uitab.ColumnName;

            % Normalize varNames to a cellstr
            if ischar(varNames)
                % Might be 'numbered' or something similar -> ignore and auto-generate
                varNames = {};
            end
            if isempty(varNames)
                varNames = {};
            end

            % Ensure varNames is a cellstr
            if ~iscell(varNames)
                varNames = cellstr(varNames);
            end

            % 4) Fix length mismatch between varNames and actual columns
            if numel(varNames) < nCols
                % Pad with default names: Var1, Var2, ...
                for k = numel(varNames)+1:nCols
                    varNames{end+1} = sprintf('Var%d', k);
                end
            elseif numel(varNames) > nCols
                % Too many names -> truncate
                varNames = varNames(1:nCols);
            end

            % 5) Try to convert numeric-like cell array to numeric matrix
            if iscell(data)
                % Replace empties with NaN (so cell2mat works)
                isEmpty = cellfun(@isempty, data);
                data(isEmpty) = {NaN};

                % If everything is numeric/logical now, make a numeric table
                if all(cellfun(@(c) isnumeric(c) || islogical(c), data), 'all')
                    mat = cell2mat(data);  % N×M double/logical
                    T = array2table(mat, 'VariableNames', varNames);
                    return;
                end
            end

            % 6) Fallback: let cell2table handle it (e.g. mixed types)
            T = cell2table(data, 'VariableNames', varNames);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % create structs used for mapping tags to buttons
            % app.Panels.(key) calls corresponding panel
            app.Panels = struct( ...
                'Material',     app.Panel_Material, ...
                'Section',      app.Panel_Section, ...
                'Beam',         app.Panel_Beam, ...
                'Actions',      app.Panel_Actions, ...
                'Result',      app.Panel_Result);

            app.Tables = struct( ...
                'MaterialTable',      app.Table_Material, ...
                'SectionTable',       app.Table_Section, ...
                'NodeTable',          app.Table_Node, ...
                'BeamTable',          app.Table_Beam, ...
                'SupportTable',       app.Table_Support, ...
                'SpringTable',        app.Table_Spring, ...
                'NodalLoadTable',     app.Table_NodalLoad, ...
                'PointLoadTable',     app.Table_PointLoad, ...
                'DistrLoadTable',     app.Table_DistrLoad, ...
                'ForcedDisplTable',   app.Table_ForcedDispl, ...
                'InfluenceLineTable', app.Table_InfluenceLine);
            
            % list of support types in dropdown menu
            app.SupportTypes = { ...
                'Eingespannt', ...             % 1
                'Gelenkig', ...                % 2
                'Rolllager y-Richtung', ...    % 3
                'Rolllager x-Richtung', ...    % 4
                'Gleitlager y-Richtung', ...   % 5
                'Gleitlager x-Richtung'};      % 6

            app.ColumnFormatTemplates.SupportTable = {'numeric', app.SupportTypes};

         
        end

        % Button pushed function: Button_Actions, Button_Beam, 
        % ...and 3 other components
        function Button_LeftMenuPushed(app, event)
            % get tag of the pressed button
            key = string(event.Source.Tag);

            % remove the other panel
            panels = struct2cell(app.Panels);
            set([panels{:}], 'Visible', 'off');

            % resize system viewer
            app.SystemViewer.Layout.Column = [3 4];

            % show the matching one
            app.Panels.(key).Parent = app.GridLayout;
            app.Panels.(key).Visible = 'on';
            app.Panels.(key).Layout.Row    = [1 10];
            app.Panels.(key).Layout.Column = 2;

        end

        % Button pushed function: Button_ClosePanel, Button_ClosePanel2, 
        % ...and 3 other components
        function Button_BackPushed(app, event)
            
            % resize system viewer
            app.SystemViewer.Layout.Column = [2 4];
            
            % remove panel
            event.Source.Parent.Parent.Visible = 'off';
        end

        % Button pushed function: Button_Calculate
        function Button_CalculatePushed(app, event)
            try
                in = guiToInput(app);
                %out = inputUmwandeln(in);
                [Model, status, Meldung, issues] = MatrizenStatik(in);
                disp('Run completed successfully');
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: Button_TestInput
        function Button_TestInputPushed(app, event)
            app.Table_InfluenceLine.Data = table(4, 1, 3, 'VariableNames', {'TypEL','Knoten','Richtung'});
            %Material
            EModul = [200];
            app.Table_Material.Data = table(EModul);

            %Querschnitte ( <-- irgendwelche Zahlen)
            %nQuerschnitte = size(Querschnitt,1);
            Material = [1, 1]';     %both Sections have the same material
            nQuerschnitte = size(Material,1);
            Flaeche = [0.2,0.2*2.5]';
            Traegheitsmoment = [0.16,0.16]';
            dehnstarr = false(nQuerschnitte,1);
            biegesteif = false(nQuerschnitte,1);
            app.Table_Section.Data = table(Material, Flaeche, Traegheitsmoment, dehnstarr, biegesteif);

            %Knoten
            xPos = [0,10,20]';
            yPos = [0,5,0]';
            app.Table_Node.Data = table(xPos, yPos);

            %Stäbe
            StartKnoten = [1,2]';
            EndKnoten   = [2,3]';
            Querschnitt = [1,2]';
            GelenkStabAnfang = [3,3]';
            GelenkStabende   = [3,3]';
            app.Table_Beam.Data = table(StartKnoten, EndKnoten, Querschnitt, GelenkStabAnfang, GelenkStabende);

            %Federn
            Knoten = []';
            Feder  = []';
            Betrag = []';
            app.Table_Spring.Data = table(Knoten, Feder, Betrag);
            
            %Lager
            Knoten = [1,3]';
            app.Table_Support.ColumnFormat = {[], app.SupportTypes};
            app.Table_Support.Data = {Knoten(1) 'Gelenkig';Knoten(2) 'Gelenkig'};

            %Zwängungen
            Knoten = [];
            Richtung = [];
            Wert = [];
            app.Table_ForcedDispl.Data = table(Knoten, Richtung, Wert);

            %KnotenLasten
            Knoten   = [2,2]';
            Richtung = [1,2]';
            Wert     = [100,300]';
            app.Table_NodalLoad.Data = table(Knoten, Richtung, Wert);

            %StabLasten (konzentriert)
            Stab = []';
            Richtung = []';
            Wert = []';
            StartPosition = []';
            app.Table_PointLoad.Data = table(Stab, Richtung, Wert, StartPosition);

            %StabLasten (verteilt)
            Stab = [];
            Richtung = [];
            Wert = [];
            StartPosition = [];
            EndPosition   = [];
            app.Table_DistrLoad.Data = table(Stab, Richtung, Wert, StartPosition, EndPosition);
        end

        % Button pushed function: AddRowButton_10, AddRowButton_11, 
        % ...and 9 other components
        function Button_AddRowPushed(app, event)
            % determine key based on Tag
            key = string(event.Source.Tag);
            
            % determine amount of columns
            %nCols = size(app.Tables.(key).ColumnName, 1);
            
            % tesing phase only for supports 
            if isfield(app.ColumnFormatTemplates, key)
                app.Tables.(key).ColumnFormat = app.ColumnFormatTemplates.(key);
                emptyRow = {[] 'Choose'};
                app.Tables.(key).Data = [app.Tables.(key).Data; emptyRow];
                return
            end
            
            % required for testInput at the moment
            if istable(app.Tables.(key).Data)
                % Create a table row with NaNs
                nCols = width(app.Tables.(key).Data);
                varNames = app.Tables.(key).Data.Properties.VariableNames;
                newRow = array2table(nan(1, nCols), 'VariableNames', varNames);
                app.Tables.(key).Data = [app.Tables.(key).Data; newRow];
            else
                % Fallback: old behavior
                nCols = numel(app.Tables.(key).ColumnName);
                emptyRow = repmat({[]}, 1, nCols);
                app.Tables.(key).Data = [app.Tables.(key).Data; emptyRow];
            end
         
        end

        % Button pushed function: Button_RemoveRow10, RemoveRowButton_10, 
        % ...and 9 other components
        function Button_RemoveRowPushed(app, event)
            % determine key based on Tag
            key = string(event.Source.Tag);
            
            % nothing selected --> pushing button does nothing
            if isempty(app.rowNumber)
                return
            end
            
            %remove selected row
            app.Tables.(key).Data(app.rowNumber,:) = [];
            
            % clear stale selection
            app.rowNumber = [];
        end

        % Cell selection callback: Table_Beam, Table_DistrLoad, 
        % ...and 9 other components
        function Table_GetRowCellSelection(app, event)
            % do nothing when nothing is selected
            if isempty(event.Indices)
                app.rowNumber = [];   % nothing selected
                return;
            end

            % extract unique row numbers from all selected cells
            app.rowNumber = unique(event.Indices(:, 1));
         
        end

        % Value changed function: DropDown_GewnschterOutput
        function DropDown_InfluenceLineOptions(app, event)
            value = app.DropDown_GewnschterOutput.Value;
            if value == "Einflusslinie"
                app.SubPanel_InfluenceLine.Visible = 'on';
            else
                app.SubPanel_InfluenceLine.Visible = 'off';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SystemViewer = uiaxes(app.GridLayout);
            title(app.SystemViewer, 'System Vorschau')
            xlabel(app.SystemViewer, 'X')
            ylabel(app.SystemViewer, 'Y')
            zlabel(app.SystemViewer, 'Z')
            app.SystemViewer.SubtitleFontWeight = 'bold';
            app.Button_Beam = uibutton(app.GridLayout, 'push');
            app.Button_Beam.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Beam.Tag = 'Beam';
            app.Button_Beam.Text = 'Stäbe';
            app.Button_Section = uibutton(app.GridLayout, 'push');
            app.Button_Section.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Section.Tag = 'Section';
            app.Button_Section.Text = 'Querschnitt';
            app.Button_Result = uibutton(app.GridLayout, 'push');
            app.Button_Result.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Result.Tag = 'Result';
            app.Button_Result.Text = 'Resultate';
            app.Button_Material = uibutton(app.GridLayout, 'push');
            app.Button_Material.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Material.Tag = 'Material';
            app.Button_Material.Text = 'Material';
            app.Button_TestInput = uibutton(app.GridLayout, 'push');
            app.Button_TestInput.ButtonPushedFcn = createCallbackFcn(app, @Button_TestInputPushed, true);
            app.Button_TestInput.Text = 'Test Input';
            app.Button_Actions = uibutton(app.GridLayout, 'push');
            app.Button_Actions.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Actions.Tag = 'Actions';
            app.Button_Actions.Text = 'Einwirkungen';
            app.Panel_Actions = uipanel(app.UIFigure);
            app.Panel_Actions.Visible = 'off';
            app.GridLayout9 = uigridlayout(app.Panel_Actions);
            app.SubPanel_ForcedDispl = uipanel(app.GridLayout9);
            app.SubPanel_ForcedDispl.Title = 'Zwängungen';
            app.GridLayout17 = uigridlayout(app.SubPanel_ForcedDispl);
            app.Table_ForcedDispl = uitable(app.GridLayout17);
            app.Table_ForcedDispl.ColumnName = {'Knoten'; 'Richtung'; 'Wert'};
            app.Table_ForcedDispl.RowName = {};
            app.Table_ForcedDispl.ColumnEditable = [true true true];
            app.Table_ForcedDispl.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_8 = uibutton(app.GridLayout17, 'push');
            app.AddRowButton_8.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_8.Tag = 'ForcedDisplTable';
            app.AddRowButton_8.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_8.Text = '';
            app.RemoveRowButton_8 = uibutton(app.GridLayout17, 'push');
            app.RemoveRowButton_8.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_8.Tag = 'ForcedDisplTable';
            app.RemoveRowButton_8.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_8.Text = '';
            app.SubPanel_DistrLoad = uipanel(app.GridLayout9);
            app.SubPanel_DistrLoad.Title = 'Verteilte Lasten';
            app.GridLayout16 = uigridlayout(app.SubPanel_DistrLoad);
            app.Table_DistrLoad = uitable(app.GridLayout16);
            app.Table_DistrLoad.ColumnName = {'Stab'; 'Richtung'; 'Wert'; 'StartPosition'; 'EndPosition'};
            app.Table_DistrLoad.RowName = {};
            app.Table_DistrLoad.ColumnEditable = [true true true true true];
            app.Table_DistrLoad.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_7 = uibutton(app.GridLayout16, 'push');
            app.AddRowButton_7.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_7.Tag = 'DistrLoadTable';
            app.AddRowButton_7.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_7.Text = '';
            app.RemoveRowButton_7 = uibutton(app.GridLayout16, 'push');
            app.RemoveRowButton_7.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_7.Tag = 'DistrLoadTable';
            app.RemoveRowButton_7.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_7.Text = '';
            app.SubPanel_PointLoad = uipanel(app.GridLayout9);
            app.SubPanel_PointLoad.Title = 'Stab Lasten';
            app.GridLayout15 = uigridlayout(app.SubPanel_PointLoad);
            app.Table_PointLoad = uitable(app.GridLayout15);
            app.Table_PointLoad.ColumnName = {'Stab'; 'Richtung'; 'Wert'; 'StartPosition'};
            app.Table_PointLoad.RowName = {};
            app.Table_PointLoad.ColumnEditable = [true true true true];
            app.Table_PointLoad.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_6 = uibutton(app.GridLayout15, 'push');
            app.AddRowButton_6.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_6.Tag = 'PointLoadTable';
            app.AddRowButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_6.Text = '';
            app.RemoveRowButton_6 = uibutton(app.GridLayout15, 'push');
            app.RemoveRowButton_6.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_6.Tag = 'PointLoadTable';
            app.RemoveRowButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_6.Text = '';
            app.SubPanel_NodalLoad = uipanel(app.GridLayout9);
            app.SubPanel_NodalLoad.Title = 'Knoten Lasten';
            app.GridLayout14 = uigridlayout(app.SubPanel_NodalLoad);
            app.Table_NodalLoad = uitable(app.GridLayout14);
            app.Table_NodalLoad.ColumnName = {'Knoten'; 'Richtung'; 'Wert'};
            app.Table_NodalLoad.RowName = {};
            app.Table_NodalLoad.ColumnEditable = [true true true];
            app.Table_NodalLoad.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_5 = uibutton(app.GridLayout14, 'push');
            app.AddRowButton_5.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_5.Tag = 'NodalLoadTable';
            app.AddRowButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_5.Text = '';
            app.RemoveRowButton_5 = uibutton(app.GridLayout14, 'push');
            app.RemoveRowButton_5.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_5.Tag = 'NodalLoadTable';
            app.RemoveRowButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_5.Text = '';
            app.Button_ClosePanel2 = uibutton(app.GridLayout9, 'push');
            app.Button_ClosePanel2.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close.png');
            app.Button_ClosePanel2.Text = '';
            app.Panel_Beam = uipanel(app.UIFigure);
            app.Panel_Beam.Visible = 'off';
            app.GridLayout8_2 = uigridlayout(app.Panel_Beam);
            app.SubPanel_Spring = uipanel(app.GridLayout8_2);
            app.SubPanel_Spring.Title = 'Feder';
            app.GridLayout13 = uigridlayout(app.SubPanel_Spring);
            app.Table_Spring = uitable(app.GridLayout13);
            app.Table_Spring.ColumnName = {'Knoten'; 'Feder'; 'Betrag'};
            app.Table_Spring.RowName = {};
            app.Table_Spring.ColumnEditable = [true true true];
            app.Table_Spring.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_4 = uibutton(app.GridLayout13, 'push');
            app.AddRowButton_4.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_4.Tag = 'SpringTable';
            app.AddRowButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_4.Text = '';
            app.RemoveRowButton_4 = uibutton(app.GridLayout13, 'push');
            app.RemoveRowButton_4.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_4.Tag = 'SpringTable';
            app.RemoveRowButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_4.Text = '';
            app.SubPanel_Support = uipanel(app.GridLayout8_2);
            app.SubPanel_Support.Title = 'Lager';
            app.GridLayout12 = uigridlayout(app.SubPanel_Support);
            app.Table_Support = uitable(app.GridLayout12);
            app.Table_Support.ColumnName = {'Knoten'; 'Lager'};
            app.Table_Support.RowName = {};
            app.Table_Support.ColumnEditable = [true true];
            app.Table_Support.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_3 = uibutton(app.GridLayout12, 'push');
            app.AddRowButton_3.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_3.Tag = 'SupportTable';
            app.AddRowButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_3.Text = '';
            app.RemoveRowButton_3 = uibutton(app.GridLayout12, 'push');
            app.RemoveRowButton_3.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_3.Tag = 'SupportTable';
            app.RemoveRowButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_3.Text = '';
            app.SubPanel_Beam = uipanel(app.GridLayout8_2);
            app.SubPanel_Beam.Title = 'Stäbe';
            app.GridLayout11 = uigridlayout(app.SubPanel_Beam);
            app.Table_Beam = uitable(app.GridLayout11);
            app.Table_Beam.ColumnName = {'StartKnoten'; 'EndKnoten'; 'Querschnitt'; 'GelenkStabAnfang'; 'GelenkStabende'};
            app.Table_Beam.RowName = {};
            app.Table_Beam.ColumnEditable = [true true true true true];
            app.Table_Beam.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_2 = uibutton(app.GridLayout11, 'push');
            app.AddRowButton_2.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_2.Tag = 'BeamTable';
            app.AddRowButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_2.Text = '';
            app.RemoveRowButton_2 = uibutton(app.GridLayout11, 'push');
            app.RemoveRowButton_2.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_2.Tag = 'BeamTable';
            app.RemoveRowButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_2.Text = '';
            app.SubPanel_Node = uipanel(app.GridLayout8_2);
            app.SubPanel_Node.Title = 'Knoten';
            app.GridLayout10 = uigridlayout(app.SubPanel_Node);
            app.Table_Node = uitable(app.GridLayout10);
            app.Table_Node.ColumnName = {'xPos'; 'yPos'};
            app.Table_Node.ColumnEditable = [true true];
            app.Table_Node.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Button_AddRow = uibutton(app.GridLayout10, 'push');
            app.Button_AddRow.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.Button_AddRow.Tag = 'NodeTable';
            app.Button_AddRow.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.Button_AddRow.Text = '';
            app.Button_RemoveRow10 = uibutton(app.GridLayout10, 'push');
            app.Button_RemoveRow10.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.Button_RemoveRow10.Tag = 'NodeTable';
            app.Button_RemoveRow10.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.Button_RemoveRow10.Text = '';
            app.Button_ClosePanel = uibutton(app.GridLayout8_2, 'push');
            app.Button_ClosePanel.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close.png');
            app.Button_ClosePanel.Text = '';
            app.Panel_Material = uipanel(app.UIFigure);
            app.Panel_Material.Visible = 'off';
            app.GridLayout9_2 = uigridlayout(app.Panel_Material);
            app.Button_ClosePanel2_2 = uibutton(app.GridLayout9_2, 'push');
            app.Button_ClosePanel2_2.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel2_2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close.png');
            app.Button_ClosePanel2_2.Text = '';
            app.SubPanel_Material = uipanel(app.GridLayout9_2);
            app.SubPanel_Material.Title = 'Material';
            app.GridLayout14_2 = uigridlayout(app.SubPanel_Material);
            app.Table_Material = uitable(app.GridLayout14_2);
            app.Table_Material.ColumnName = {'EModul'};
            app.Table_Material.ColumnEditable = true;
            app.Table_Material.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_9 = uibutton(app.GridLayout14_2, 'push');
            app.AddRowButton_9.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_9.Tag = 'MaterialTable';
            app.AddRowButton_9.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_9.Text = '';
            app.RemoveRowButton_9 = uibutton(app.GridLayout14_2, 'push');
            app.RemoveRowButton_9.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_9.Tag = 'MaterialTable';
            app.RemoveRowButton_9.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_9.Text = '';
            app.Panel_Section = uipanel(app.UIFigure);
            app.Panel_Section.Visible = 'off';
            app.GridLayout9_3 = uigridlayout(app.Panel_Section);
            app.Button_ClosePanel2_3 = uibutton(app.GridLayout9_3, 'push');
            app.Button_ClosePanel2_3.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel2_3.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close.png');
            app.Button_ClosePanel2_3.Text = '';
            app.SubPanel_Section = uipanel(app.GridLayout9_3);
            app.SubPanel_Section.Title = 'Querschnitt';
            app.GridLayout14_3 = uigridlayout(app.SubPanel_Section);
            app.AddRowButton_10 = uibutton(app.GridLayout14_3, 'push');
            app.AddRowButton_10.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_10.Tag = 'SectionTable';
            app.AddRowButton_10.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_10.Text = '';
            app.RemoveRowButton_10 = uibutton(app.GridLayout14_3, 'push');
            app.RemoveRowButton_10.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_10.Tag = 'SectionTable';
            app.RemoveRowButton_10.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_10.Text = '';
            app.Table_Section = uitable(app.GridLayout14_3);
            app.Table_Section.ColumnName = {'Material'; 'Flaeche'; 'Traegheitsmoment'; 'dehnstarr'; 'biegesteif'};
            app.Table_Section.ColumnEditable = [true true true true true];
            app.Table_Section.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Panel_Result = uipanel(app.UIFigure);
            app.Panel_Result.Visible = 'off';
            app.GridLayout9_4 = uigridlayout(app.Panel_Result);
            app.Button_ClosePanel2_4 = uibutton(app.GridLayout9_4, 'push');
            app.Button_ClosePanel2_4.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel2_4.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close.png');
            app.Button_ClosePanel2_4.Text = '';
            app.GewnschterOutputLabel = uilabel(app.GridLayout9_4);
            app.GewnschterOutputLabel.Text = 'Gewünschter Output:';
            app.DropDown_GewnschterOutput = uidropdown(app.GridLayout9_4);
            app.DropDown_GewnschterOutput.Items = {'Schnittkräfte', 'Einflusslinie', 'Auflagerreaktionen'};
            app.DropDown_GewnschterOutput.ValueChangedFcn = createCallbackFcn(app, @DropDown_InfluenceLineOptions, true);
            app.DropDown_GewnschterOutput.Value = 'Schnittkräfte';
            app.SubPanel_InfluenceLine = uipanel(app.GridLayout9_4);
            app.SubPanel_InfluenceLine.Title = 'EinflussLinie';
            app.SubPanel_InfluenceLine.Visible = 'off';
            app.GridLayout14_4 = uigridlayout(app.SubPanel_InfluenceLine);
            app.AddRowButton_11 = uibutton(app.GridLayout14_4, 'push');
            app.AddRowButton_11.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_11.Tag = 'InfluenceLineTable';
            app.AddRowButton_11.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-square-o.png');
            app.AddRowButton_11.Text = '';
            app.RemoveRowButton_11 = uibutton(app.GridLayout14_4, 'push');
            app.RemoveRowButton_11.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_11.Tag = 'InfluenceLineTable';
            app.RemoveRowButton_11.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-square-o.png');
            app.RemoveRowButton_11.Text = '';
            app.Table_InfluenceLine = uitable(app.GridLayout14_4);
            app.Table_InfluenceLine.ColumnName = {'TypEL'; 'Knoten'; 'Richtung'};
            app.Table_InfluenceLine.RowName = {};
            app.Table_InfluenceLine.ColumnEditable = [true true true];
            app.Table_InfluenceLine.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Button_Calculate = uibutton(app.GridLayout9_4, 'push');
            app.Button_Calculate.ButtonPushedFcn = createCallbackFcn(app, @Button_CalculatePushed, true);
            app.Button_Calculate.Text = 'Berechnen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end